classdef Coordinate_vector_converter < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        AtEditField                  matlab.ui.control.NumericEditField
        AtEditFieldLabel             matlab.ui.control.Label
        AtEditField_2                matlab.ui.control.NumericEditField
        AtEditField_2Label           matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        CoordinateandvectorconverterLabel  matlab.ui.control.Label
        ConvertRButton               matlab.ui.control.Button
        ConvertZButton               matlab.ui.control.Button
        ConvertXYZButton             matlab.ui.control.Button
        coordinateEditField_4        matlab.ui.control.NumericEditField
        coordinateEditField_4Label   matlab.ui.control.Label
        coordinateEditField_2        matlab.ui.control.NumericEditField
        coordinateEditField_2Label   matlab.ui.control.Label
        RcoordinateEditField         matlab.ui.control.NumericEditField
        RcoordinateEditFieldLabel    matlab.ui.control.Label
        ZcoordinateEditField_2       matlab.ui.control.NumericEditField
        ZcoordinateEditField_2Label  matlab.ui.control.Label
        coordinateEditField_3        matlab.ui.control.NumericEditField
        coordinateEditField_3Label   matlab.ui.control.Label
        coordinateEditField          matlab.ui.control.NumericEditField
        coordinateEditFieldLabel     matlab.ui.control.Label
        ZcoordinateEditField         matlab.ui.control.NumericEditField
        ZcoordinateEditFieldLabel    matlab.ui.control.Label
        YcoordinateEditField         matlab.ui.control.NumericEditField
        YcoordinateEditFieldLabel    matlab.ui.control.Label
        XcoordinateEditField         matlab.ui.control.NumericEditField
        XcoordinateEditFieldLabel    matlab.ui.control.Label
        ModeSwitch                   matlab.ui.control.Switch
        ModeSwitchLabel              matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        convert_cart
    end

    properties (Access = private)
        precision double = 5;
    end
    
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertXYZButton
        function ConvertXYZButtonPushed(comp, event)
            % comp.precision = 5; This is the default -> It's been declared a
            % private varaible (meaning all functions inside the app can
            % access it) from the properties tab.

            %Convert from cartesian:
            x =  comp.XcoordinateEditField.Value;
            y =  comp.YcoordinateEditField.Value;
            z_cart =  comp.ZcoordinateEditField.Value;

            if comp.ModeSwitch.Value == "Coordinate" % Coordinate conversion
            
            % convert to cylindrical:
            comp.coordinateEditField.Value = round(sqrt(x^2 + y^2), comp.precision); % r coordinate
            comp.coordinateEditField_3.Value = round(atan(y/x) *(180/pi), comp.precision); % phi_cyl coordinate
            comp.ZcoordinateEditField_2.Value = z_cart; % z_cyl coordinate
            
            % convert to spherical:
            comp.RcoordinateEditField.Value = sqrt(x^2 + y^2 + z_cart^2); % R coordinate
            comp.coordinateEditField_2.Value = acos(z_cart/sqrt(x^2 + y^2 + z_cart^2)) *(180/pi); % theta coordinate
            comp.coordinateEditField_4.Value = atan(y/x) *(180/pi); % phi_sph coordinate

            else % Vector conversion:

                % convert to cylindrical:
                cos_phi = x/sqrt(x^2 + y^2);
                sin_phi = y/sqrt(x^2 + y^2);

                cart_to_cyl_matrix = [cos_phi, sin_phi, 0; -sin_phi, cos_phi, 0; 0, 0, 1];
                cart_vec = [x; y ;z_cart];

                cyl_vec = cart_to_cyl_matrix * cart_vec; %Compute the vector components in terms of cylindrical unit vectors.
                
                comp.coordinateEditField.Value = round(cyl_vec(1, 1), comp.precision); % r vector component
                comp.coordinateEditField_3.Value = round(cyl_vec(2, 1), comp.precision) ; % phi_cyl vector component
                comp.ZcoordinateEditField_2.Value = cyl_vec(3, 1); % z_cyl vector component
            
                % convert to spherical:
                cos_phi = x/sqrt(x^2 + y^2);
                sin_phi = y/sqrt(x^2 + y^2);
                
                cos_theta = z_cart/sqrt(x^2 + y^2 +z_cart^2);
                sin_theta = sqrt(x^2 + y^2)/sqrt(x^2 + y^2 +z_cart^2);

                comp.RcoordinateEditField.Value = round(sin_theta *cos_phi*x + sin_theta * sin_phi *y + cos_theta * z_cart, comp.precision); % R vector component
                comp.coordinateEditField_2.Value = round((cos_theta *cos_phi*x + cos_theta * sin_phi *y + -sin_theta * z_cart), comp.precision); %theta vector component
                comp.coordinateEditField_4.Value = round( (-sin_phi * x + cos_phi * y), comp.precision); %phi_sph vector component
                
            end
        end

        % Button pushed function: ConvertZButton
        function ConvertZButtonPushed(comp, event)
            %Convert from cylindrical:
            rho = comp.coordinateEditField.Value;
            phi_cyl = comp.coordinateEditField_3.Value * (pi/180);
            z_cyl = comp.ZcoordinateEditField_2.Value;

            if comp.ModeSwitch.Value == "Coordinate" % Coordinate conversion
            
                % convert to cartesian:
                comp.XcoordinateEditField.Value = round(rho * cos(phi_cyl), comp.precision); % x coordinate
                comp.YcoordinateEditField.Value = round (rho * sin(phi_cyl), comp.precision); % y coordinate
                comp.ZcoordinateEditField.Value = round(z_cyl, comp.precision); % z coordinate
    
                % convert to spherical:
                comp.RcoordinateEditField.Value = round(sqrt(rho^2 + z_cyl^2), comp.precision); % R  coordinate
                comp.coordinateEditField_2.Value = round( (pi/2 - acos(rho/ sqrt(rho^2 + z_cyl^2)) ) *(180/pi), comp.precision); % theta coordinate
                comp.coordinateEditField_4.Value = round (phi_cyl *(180/pi), comp.precision); % phi_sph coordinate

            else % Vector conversion:

                rho_comp = comp.coordinateEditField.Value;
                phi_cyl_comp = comp.coordinateEditField_3.Value; %Vector component, not an angle; don't multiply by pi/180.
                z_cyl_comp = comp.ZcoordinateEditField_2.Value;
                
                cyl_vec = [rho_comp; phi_cyl_comp ;z_cyl_comp];

                %Convert to cartesian:

                % Get the trigonometric functions of the angles relating to
                % the vector's position:

                cos_phi = cos(comp.AtEditField_2.Value *(pi/180));
                sin_phi = sin(comp.AtEditField_2.Value *(pi/180));

                cyl_to_cart_matrix = [cos_phi, sin_phi, 0; -sin_phi, cos_phi, 0; 0, 0, 1]^(-1);
    
                cart_vec = cyl_to_cart_matrix * cyl_vec; %Compute the vector components in terms of cartesian unit vectors.

                comp.XcoordinateEditField.Value = round(cart_vec(1, 1), comp.precision); % x vector component
                comp.YcoordinateEditField.Value = round(cart_vec(2, 1), comp.precision); % y vector component
                comp.ZcoordinateEditField.Value = round(cart_vec(3, 1), comp.precision); % z_cart vector component
    
                %Convert to spherical:

                % Get the trigonometric functions of the angles relating to
                % the vector's position:

                cos_theta = cos(comp.AtEditField.Value *(pi/180));
                sin_theta = sin(comp.AtEditField.Value *(pi/180));
               
                cyl_to_sph_matrix = [sin_theta, 0, cos_theta; cos_theta, 0, -sin_theta; 0, 1, 0];
    
                sph_vec = cyl_to_sph_matrix * cyl_vec; %Compute the vector components in terms of spherical unit vectors.

                comp.RcoordinateEditField.Value = round(sph_vec(1, 1), comp.precision); % R vector component
                comp.coordinateEditField_2.Value = round(sph_vec(2, 1), comp.precision); % theta vector component
                comp.coordinateEditField_4.Value = round(sph_vec(3, 1), comp.precision); % phi_sph vector component

                
            end

        end

        % Button pushed function: ConvertRButton
        function ConvertRButtonPushed(comp, event)
            %Convert from spherical coordinates:
            R = comp.RcoordinateEditField.Value;
            theta = comp.coordinateEditField_2.Value * (pi/180);
            phi_sph = comp.coordinateEditField_4.Value * (pi/180);
            
            if comp.ModeSwitch.Value == "Coordinate" % Coordinate conversion

                % convert to cartesian coordiantes:
                comp.XcoordinateEditField.Value = round(R * sin(theta) * cos(phi_sph), comp.precision); % x coordinate
                comp.YcoordinateEditField.Value = round(R * sin(theta) * sin(phi_sph), comp.precision); % y coordinate
                comp.ZcoordinateEditField.Value = round(R * cos(theta), comp.precision); % z coordinate
                
                %Convert to cylindrical coordinates:
                comp.coordinateEditField.Value = round(R * sin(theta), comp.precision); % rho coordinate
                comp.coordinateEditField_3.Value = round(phi_sph * (180/pi), comp.precision); % phi coordinate
                comp.ZcoordinateEditField_2.Value = round(R * cos(theta), comp.precision); % z_cyl coordinate
            
            else % Vector conversion:
                
                % Get the trigonometric functions of the angles relating to
                % the vector's position
                cos_phi = cos(comp.AtEditField_2.Value *(pi/180));
                sin_phi = sin(comp.AtEditField_2.Value *(pi/180));
                cos_theta = cos(comp.AtEditField.Value *(pi/180));
                sin_theta = sin(comp.AtEditField.Value *(pi/180));
                
                % The componenets of the vector:
                R_component = comp.RcoordinateEditField.Value;
                theta_component = comp.coordinateEditField_2.Value; %Vector component, not an angle; don't multiply by pi/180.
                phi_sph_component = comp.coordinateEditField_4.Value; %Vector component, not an angle; don't multiply by pi/180.

                sph_vec = [R_component; theta_component ;phi_sph_component];
    

                %Convert to cartesian unit vector representation:
                sph_to_cart_matrix = [sin_theta * cos_phi, cos_theta * cos_phi, -sin_phi; sin_theta * sin_phi , cos_theta * sin_phi, cos_phi; cos_theta, -sin_theta, 0];
                
                cart_vec = sph_to_cart_matrix * sph_vec; %Compute the vector components in terms of cartesian unit vectors.

                comp.XcoordinateEditField.Value = round(cart_vec(1, 1), comp.precision); % x vector component
                comp.YcoordinateEditField.Value = round(cart_vec(2, 1), comp.precision); % y vector component
                comp.ZcoordinateEditField.Value = round(cart_vec(3, 1), comp.precision); % z_cart vector component
    
                %Convert to cylindrical:
                sph_to_cyl_matrix = [sin_theta, cos_theta, 0; 0, 0, 1; cos_theta, -sin_theta, 0];
                    
                cyl_vec = sph_to_cyl_matrix * sph_vec; %Compute the vector components in terms of cylindrical unit vectors.

                comp.coordinateEditField.Value = round(cyl_vec(1, 1), comp.precision); % rho vector component
                comp.coordinateEditField_3.Value = round(cyl_vec(2, 1), comp.precision); % phi_cyl vector component
                comp.ZcoordinateEditField_2.Value = round(cyl_vec(3, 1), comp.precision); % z_cyl vector component

            end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(comp, event)
            value = comp.ModeSwitch.Value;
            if value == "Coordinate"
                comp.AtEditField.Visible = "off";
                comp.AtEditField_2.Visible = "off";
                comp.AtEditFieldLabel.Visible = "off";
                comp.AtEditField_2Label.Visible = "off";
                comp.Label.Visible = "off";

                comp.XcoordinateEditFieldLabel.Text = "X coordinate";
                comp.YcoordinateEditFieldLabel.Text = "Y coordinate";
                comp.ZcoordinateEditFieldLabel.Text = "Z coordinate";

                comp.coordinateEditFieldLabel.Text = "ρ coordinate";
                comp.coordinateEditField_3Label.Text = "Φ coordinate";
                comp.ZcoordinateEditField_2Label.Text = "Z coordinate";

                comp.RcoordinateEditFieldLabel.Text = "R coordinate";
                comp.coordinateEditField_2Label.Text = "θ coordinate";
                comp.coordinateEditField_4Label.Text = "Φ coordinate";


            else
                comp.AtEditField.Visible = "on";
                comp.AtEditField_2.Visible = "on";
                comp.AtEditFieldLabel.Visible = "on";
                comp.AtEditField_2Label.Visible = "on";
                comp.Label.Visible = "on";


                comp.XcoordinateEditFieldLabel.Text = "X component";
                comp.YcoordinateEditFieldLabel.Text = "Y component";
                comp.ZcoordinateEditFieldLabel.Text = "Z component";

                comp.coordinateEditFieldLabel.Text = "ρ component";
                comp.coordinateEditField_3Label.Text = "Φ component";
                comp.ZcoordinateEditField_2Label.Text = "Z component";

                comp.RcoordinateEditFieldLabel.Text = "R component";
                comp.coordinateEditField_2Label.Text = "θ component";
                comp.coordinateEditField_4Label.Text = "Φ component";

            end
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 744 346];
            comp.ModeSwitchLabel = uilabel(comp);
            comp.ModeSwitchLabel.Text = 'Mode';
            comp.ModeSwitch = uiswitch(comp, 'slider');
            comp.ModeSwitch.Items = {'Coordinate', 'Vector'};
            comp.ModeSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ModeSwitchValueChanged, true);
            comp.ModeSwitch.Value = 'Coordinate';
            comp.XcoordinateEditFieldLabel = uilabel(comp);
            comp.XcoordinateEditFieldLabel.Text = 'X coordinate';
            comp.XcoordinateEditField = uieditfield(comp, 'numeric');
            comp.XcoordinateEditField.Value = 3;
            comp.YcoordinateEditFieldLabel = uilabel(comp);
            comp.YcoordinateEditFieldLabel.Text = 'Y coordinate';
            comp.YcoordinateEditField = uieditfield(comp, 'numeric');
            comp.YcoordinateEditField.Value = 4;
            comp.ZcoordinateEditFieldLabel = uilabel(comp);
            comp.ZcoordinateEditFieldLabel.Text = 'Z coordinate';
            comp.ZcoordinateEditField = uieditfield(comp, 'numeric');
            comp.ZcoordinateEditField.Value = 5;
            comp.coordinateEditFieldLabel = uilabel(comp);
            comp.coordinateEditFieldLabel.Text = 'ρ coordinate';
            comp.coordinateEditField = uieditfield(comp, 'numeric');
            comp.coordinateEditField_3Label = uilabel(comp);
            comp.coordinateEditField_3Label.Text = 'Φ coordinate';
            comp.coordinateEditField_3 = uieditfield(comp, 'numeric');
            comp.ZcoordinateEditField_2Label = uilabel(comp);
            comp.ZcoordinateEditField_2Label.Text = ' Z coordinate';
            comp.ZcoordinateEditField_2 = uieditfield(comp, 'numeric');
            comp.RcoordinateEditFieldLabel = uilabel(comp);
            comp.RcoordinateEditFieldLabel.Text = 'R coordinate';
            comp.RcoordinateEditField = uieditfield(comp, 'numeric');
            comp.coordinateEditField_2Label = uilabel(comp);
            comp.coordinateEditField_2Label.Text = 'θ coordinate';
            comp.coordinateEditField_2 = uieditfield(comp, 'numeric');
            comp.coordinateEditField_4Label = uilabel(comp);
            comp.coordinateEditField_4Label.Text = 'Φ coordinate';
            comp.coordinateEditField_4 = uieditfield(comp, 'numeric');
            comp.ConvertXYZButton = uibutton(comp, 'push');
            comp.ConvertXYZButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ConvertXYZButtonPushed, true);
            comp.ConvertXYZButton.Text = 'Convert XYZ';
            comp.ConvertZButton = uibutton(comp, 'push');
            comp.ConvertZButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ConvertZButtonPushed, true);
            comp.ConvertZButton.Text = 'Convert ρΦZ';
            comp.ConvertRButton = uibutton(comp, 'push');
            comp.ConvertRButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ConvertRButtonPushed, true);
            comp.ConvertRButton.Text = 'Convert RθΦ';
            comp.CoordinateandvectorconverterLabel = uilabel(comp);
            comp.CoordinateandvectorconverterLabel.Text = 'Coordinate and vector converter';
            comp.Label = uilabel(comp);
            comp.Label.Visible = 'off';
            comp.Label.Text = {'Determine where the vector'; ' is located (only required'; ' when converting from'; ' cylindricalor spherical '; 'vectors)'};
            comp.AtEditField_2Label = uilabel(comp);
            comp.AtEditField_2Label.Visible = 'off';
            comp.AtEditField_2Label.Text = 'At Φ = ';
            comp.AtEditField_2 = uieditfield(comp, 'numeric');
            comp.AtEditField_2.Visible = 'off';
            comp.AtEditFieldLabel = uilabel(comp);
            comp.AtEditFieldLabel.Visible = 'off';
            comp.AtEditFieldLabel.Text = 'At θ = ';
            comp.AtEditField = uieditfield(comp, 'numeric');
            comp.AtEditField.Visible = 'off';
            comp.AtEditField.Value = 90;
        end
    end
end